classdef APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        CollisionButton            matlab.ui.control.Button
        LightCurtainsButton        matlab.ui.control.Button
        OnOfLamp                   matlab.ui.control.Lamp
        LampLabel                  matlab.ui.control.Label
        RestartButton              matlab.ui.control.Button
        ResumeButton               matlab.ui.control.Button
        StopButton                 matlab.ui.control.Button
        StartButton                matlab.ui.control.Button
        StateoutputEditField       matlab.ui.control.EditField
        StateoutputEditFieldLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            startSimulation;
            app.StateoutputEditField.Value = "Start Simulation"
            app.OnOfLamp.Color = [0.00,1.00,0.00];
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            stopSimulation;
            app.StateoutputEditField.Value = "Stop Simulation"
            app.OnOfLamp.Color = [1.00,0.00,0.00];
        end

        % Button pushed function: ResumeButton
        function ResumeButtonPushed(app, event)
            resumeSimulation; 
            app.StateoutputEditField.Value = "Resume Simulation"
        end

        % Button pushed function: RestartButton
        function RestartButtonPushed(app, event)
            resetSimulation; 
            app.StateoutputEditField.Value = "Re-Start Simulation"
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StateoutputEditFieldLabel = uilabel(app.UIFigure);
            app.StateoutputEditFieldLabel.Text = 'State output';
            app.StateoutputEditField = uieditfield(app.UIFigure, 'text');
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ResumeButton = uibutton(app.UIFigure, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.ResumeButton.Text = 'Resume';
            app.RestartButton = uibutton(app.UIFigure, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.RestartButton.Text = 'Restart';
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = 'Lamp';
            app.OnOfLamp = uilamp(app.UIFigure);
            app.LightCurtainsButton = uibutton(app.UIFigure, 'push');
            app.LightCurtainsButton.Text = 'Light Curtains';
            app.CollisionButton = uibutton(app.UIFigure, 'push');
            app.CollisionButton.Text = 'Collision ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end