classdef APP_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        UR3Tab                      matlab.ui.container.Tab
        UR3CartesianControlLabel    matlab.ui.control.Label
        YEditField                  matlab.ui.control.NumericEditField
        YEditFieldLabel             matlab.ui.control.Label
        ZEditField                  matlab.ui.control.NumericEditField
        ZEditFieldLabel             matlab.ui.control.Label
        XEditField                  matlab.ui.control.NumericEditField
        XEditFieldLabel             matlab.ui.control.Label
        Link6Slider_2               matlab.ui.control.Slider
        Link6Slider_2Label          matlab.ui.control.Label
        Link5Slider                 matlab.ui.control.Slider
        Link5SliderLabel            matlab.ui.control.Label
        Link4Slider                 matlab.ui.control.Slider
        Link4SliderLabel            matlab.ui.control.Label
        Link3Slider                 matlab.ui.control.Slider
        Link3SliderLabel            matlab.ui.control.Label
        Link2Slider                 matlab.ui.control.Slider
        Link2SliderLabel            matlab.ui.control.Label
        Link1Slider                 matlab.ui.control.Slider
        Link1Label                  matlab.ui.control.Label
        PandaTab                    matlab.ui.container.Tab
        PandaCartesianControlLabel  matlab.ui.control.Label
        ZEditField_2                matlab.ui.control.NumericEditField
        ZEditField_2Label           matlab.ui.control.Label
        YEditField_2                matlab.ui.control.NumericEditField
        YEditField_2Label           matlab.ui.control.Label
        XEditField_2                matlab.ui.control.NumericEditField
        XEditField_2Label           matlab.ui.control.Label
        Link3Slider_2               matlab.ui.control.Slider
        Link3Slider_2Label          matlab.ui.control.Label
        Link6Slider                 matlab.ui.control.Slider
        Link6SliderLabel            matlab.ui.control.Label
        Link5Slider_2               matlab.ui.control.Slider
        Link5Slider_2Label          matlab.ui.control.Label
        Link4Slider_2               matlab.ui.control.Slider
        Link4Slider_2Label          matlab.ui.control.Label
        Link7Slider                 matlab.ui.control.Slider
        Link7SliderLabel            matlab.ui.control.Label
        Link2Slider_2               matlab.ui.control.Slider
        Link2Slider_2Label          matlab.ui.control.Label
        Link1Slider_2               matlab.ui.control.Slider
        Link1Slider_2Label          matlab.ui.control.Label
        EstopButton                 matlab.ui.control.StateButton
        CollisionButton             matlab.ui.control.Button
        LightCurtainsButton         matlab.ui.control.Button
        OnOfLamp                    matlab.ui.control.Lamp
        LampLabel                   matlab.ui.control.Label
        RestartButton               matlab.ui.control.Button
        ResumeButton                matlab.ui.control.Button
        StopButton                  matlab.ui.control.Button
        StartButton                 matlab.ui.control.Button
        StateoutputEditField        matlab.ui.control.EditField
        StateoutputEditFieldLabel   matlab.ui.control.Label
    end

    
    properties (Access = public)
        % isRunning = false;  % Control flag for the loop
        % stopLoop = false;   % Flag to completely stop the loop
        % currentIteration = 1; % Track the current position in the loop
        % totalIterations = 100; % Set a total number of iterations

         motionHandler1  % UR3 Motion Handler
         motionHandler2  % Panda Motion Handler

         eStop = false;
         isRunning = true;
    

         %UR3 Setup
         ur3_joint1 = 0;
         ur3_joint2 = 0;
         ur3_joint3 = 0;
         ur3_joint4 = 0;
         ur3_joint5 = 0;
         ur3_joint6 = 0;
         ur3_velocityX = 0;
         ur3_velocityY = 0;
         ur3_velocityZ = 0;

         %Panda Setup
         panda_joint1 = 0;
         panda_joint2 = 0;
         panda_joint3 = 0;
         panda_joint4 = 0;
         panda_joint5 = 0;
         panda_joint6 = 0;
         panda_joint7 = 0;
         panda_velocityX = 0;
         panda_velocityY = 0;
         panda_velocityZ = 0;

    end
    
    methods (Access = private)
        
    function runSimulation(app)
            app.isRunning = true;
        
            % Resume the loop from the current position
            % for i = app.currentIteration:app.totalIterations
            %     if app.isRunning == false || app.stopLoop == true
            %         app.currentIteration = i; % Save the current state
            %         disp(['Pausing at iteration: ', num2str(i)]);
            %         break;  % Exit loop to pause or stop
            %     end
        
                % Simulation logic (replace with your actual code)
                % disp(['Simulation running... Iteration: ', num2str(i)]);
        
                % pause(1);  % Simulate a delay
        
            %     % Check if stop condition is met
            %     if app.stopLoop
            %         disp('Stopping simulation.');
            %         break;
            %     end
            % end
            % 
            % % If simulation completes
            % if app.currentIteration >= app.totalIterations
            %     disp('Simulation completed.');
            %     app.isRunning = false;  % Reset running status
            %     app.currentIteration = 1;  % Reset to initial state
            % end

            % [app.motionHandler1, app.motionHandler2] = initializeMotionHandlers();
            % app.motionHandler1 = motionhandlersTest()

            while true
                 % app.motionHandler1.runRMRC(transl(-0.3, 0.2, 0.7), transl(-0.4, 0.3, 0.7), 5,0.05);
                 disp(app.motionHandler1.running)
                 app.motionHandler1.runRMRC(transl(-0.3, 0.3, 0.7), 5,0.05);
                 disp(app.motionHandler1.running)
                 disp(app.motionHandler1.getCurrentEndTr);
                 pause(0.5);
                 % app.motionHandler1.runRMRC(transl(-0.4, 0.3, 0.7), transl(-0.3, 0.2, 0.7),  5,0.05);
                 app.motionHandler1.runRMRC(transl(0.5, 0.3, 0.7), 5,0.05);
                 disp(app.motionHandler1.running)
                 disp(app.motionHandler1.getCurrentEndTr);
                 disp(app.motionHandler1.running)
                 pause(1)
            %      app.isRunning = true;
            end


    end
        
    end
    
    methods (Access = public)
        
        function animator(app,robots,endTrArr)
             
            for j = 1:size(endTrArr)

                % Initial joint configuration
                steps = 50;
                endTr = endTrArr(j);
                % Solve for joint configurations at the start and end transformations
                % qStart = app.motionHandler1.robot.model.ikcon(startTr, q0);  % Initial pose
                qStart = app.motionHandler1.getCurrentPos();
                qEnd = app.motionHandler1.robot.model.ikcon(endTr, qStart);  % End pose, starting from qStart
    
                % Generate joint trajectory between the start and end configurations
                qMatrix = jtraj(qStart, qEnd, steps);  % Generate smooth trajectory


                % Animate the robot following the joint trajectory
                for i = 1:steps
                    q_current = qMatrix(i, :);  % Current joint configuration
                    app.motionHandler1.robot.model.animate(q_current);  % Animate the robot
    
                    % Check for collisions
                    app.motionHandler1.checkForCollisionAndStop();
                    if ~app.motionHandler1.running
                        break;  % Stop motion if a collision is detected
                    end
    
                    % Update the ellipsoid positions and draw them during animation
                    app.motionHandler1.collisionHandler.drawEllipsoids();
    
                    pause(0.05);  % Adjust the pause duration as needed for smooth animation
                end
            end
    end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % [app.motionHandler1, app.motionHandler2] = initializeMotionHandlers();
            %app.motionHandler1 = motionhandlersTest();
            
            % app.isRunning = true;
            app.eStop = false;
            Main(app);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)

            % if ~app.isRunning
            %     app.StateoutputEditField.Value = "Start Simulation";
            %     app.OnOfLamp.Color = [0.00, 1.00, 0.00];
            %     app.stopLoop = false;  % Reset stop flag
            %     runSimulation(app);    % Start simulation
            % 
            %      % Call a function in the external script to initialize the robot and motion
            %      % [app.motionHandler1, app.motionHandler2] = initializeMotionHandlers();  % External script
            % 
            %      % Start the robot motion
            % while app.isRunning
            %      % app.motionHandler1.runRMRC(transl(-0.3, 0.2, 0.7), transl(-0.4, 0.3, 0.7), 5,0.05);
            %      app.motionHandler1.runRMRC(transl(-0.3, 0.3, 0.7), 5,0.05);
            %      disp(app.motionHandler1.running)
            %      disp(app.motionHandler1.getCurrentEndTr);
            %      pause(0.5);
            %      % app.motionHandler1.runRMRC(transl(-0.4, 0.3, 0.7), transl(-0.3, 0.2, 0.7),  5,0.05);
            %      app.motionHandler1.runRMRC(transl(0.5, 0.3, 0.7), 5,0.05);
            %      disp(app.motionHandler1.running)
            %      disp(app.motionHandler1.getCurrentEndTr);
            %      pause(0.5)
            % %      app.isRunning = true;
            % end
            % runSimulation(app)

            %while true
    

            %    app.animator(transl(-0.3, 0.3, 0.7))
 
            %    app.animator(robot1, endTr1)
            %    app.animator(robot2, endTr2)

            %    disp(app.motionHandler1.running)
            %    pause(0.5)
            %    app.animator(transl(0.5, 0.3, 0.7))
            %    disp(app.motionHandler1.running)
            %    pause(0.5)
            %end

            app.isRunning = true;
            Main(app);

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % app.StateoutputEditField.Value = "Stop Simulation";
            % app.OnOfLamp.Color = [1.00, 0.00, 0.00];
            % disp('Pausing simulation.');
            % app.isRunning = false;  % Pause the loop

            %app.motionHandler1.eStop();

            %app.motionHandler1.running = false;

            % app.motionHandler2.eStop();
            app.isRunning = false;

        end

        % Button pushed function: ResumeButton
        function ResumeButtonPushed(app, event)
            app.StateoutputEditField.Value = "Resume Simulation";
            if ~app.isRunning && ~app.stopLoop
                % disp('Resuming simulation from iteration: ' + string(app.currentIteration));
                % runSimulation(app);  % Resume from where it paused
                % app.OnOfLamp.Color = [0.00, 1.00, 0.00];  % Set lamp to green (running)
                % 
                % app.motionHandler1.running = true;  % Resume the robot motion
                % app.isRunning = true;
                % % Resume the motion (you may need to call the function again or continue from a state)
                % app.motionHandler1.runIK(transl(-0.3, 0.2, 0.7), transl(-0.4, 0.3, 0.7), 50);

                % app.motionHandler1.resume;
                %app.motionHandler1.running = true;

                app.isRunning = true;
                app.eStop = false;
            end
        end

        % Button pushed function: RestartButton
        function RestartButtonPushed(app, event)
             % Stop the current simulation
             app.StateoutputEditField.Value = "Restarting Simulation";
             disp('Stopping simulation and resetting state.');
             
             app.isRunning = false;  % Stop the simulation
             app.stopLoop = true;    % Ensure the loop stops completely
             
             pause(0.1);  % Small delay to ensure the simulation halts (optional)
             
             % Reset the iteration and restart the simulation
             app.currentIteration = 1;  % Reset to the initial state
             app.stopLoop = false;      % Clear stop flag
             app.OnOfLamp.Color = [0.00, 1.00, 0.00];  % Set the lamp to green
             
             % Start the simulation again
             runSimulation(app);
        end

        % Value changed function: EstopButton
        function EstopButtonValueChanged(app, event)
            value = app.EstopButton.Value;
            % disp(~value)
            app.eStop = value;
            %app.motionHandler1.running = ~value;

        end

        % Value changed function: YEditField
        function UR3YEditFieldValueChanged(app, event)
            value = app.YEditField.Value;
            app.ur3_velocityY = value;
            
        end

        % Value changed function: ZEditField
        function UR3ZEditFieldValueChanged(app, event)
            value = app.ZEditField.Value;
            app.ur3_velocityZ = value;
            
        end

        % Value changed function: XEditField
        function UR3XEditFieldValueChanged(app, event)
            value = app.XEditField.Value;
            app.ur3_velocityX = value;
            
        end

        % Value changed function: Link6Slider_2
        function UR3Link6SliderValueChanged(app, event)
            value = app.Link6Slider_2.Value;
            app.ur3_joint6 = deg2rad(value);
            
        end

        % Value changed function: Link5Slider
        function UR3Link5SliderValueChanged(app, event)
            value = app.Link5Slider.Value;
             app.ur3_joint5 = deg2rad(value);
            
        end

        % Value changed function: Link4Slider
        function UR3Link4SliderValueChanged(app, event)
            value = app.Link4Slider.Value;
            app.ur3_joint4 = deg2rad(value);
            
        end

        % Value changed function: Link3Slider
        function UR3Link3SliderValueChanged(app, event)
            value = app.Link3Slider.Value;
            app.ur3_joint3 = deg2rad(value);
            
        end

        % Value changed function: Link2Slider
        function UR3Link2SliderValueChanged(app, event)
            value = app.Link2Slider.Value;
            app.ur3_joint2 = deg2rad(value);
            
        end

        % Value changed function: Link1Slider
        function UR3Link1SliderValueChanged(app, event)
            value = app.Link1Slider.Value;
            app.ur3_joint1 = deg2rad(value);
            
        end

        % Value changed function: ZEditField_2
        function PandaZEditFieldValueChanged(app, event)
            value = app.ZEditField_2.Value;
            app.panda_velocityZ = value;
            
        end

        % Value changed function: YEditField_2
        function PandaYEditFieldValueChanged(app, event)
            value = app.YEditField_2.Value;
            app.panda_velocityY = value;
            
        end

        % Value changed function: XEditField_2
        function PandaXEditFieldValueChanged(app, event)
            value = app.XEditField_2.Value;
            app.panda_velocityX = value;

        end

        % Value changed function: Link3Slider_2
        function PandaLink3SliderValueChanged(app, event)
            value = app.Link3Slider_2.Value;
            app.panda_joint3 = deg2rad(value);
            
        end

        % Value changed function: Link6Slider
        function PandaLink6SliderValueChanged(app, event)
            value = app.Link6Slider.Value;
            app.panda_joint6 = deg2rad(value);
            
        end

        % Value changed function: Link5Slider_2
        function PandaLink5SliderValueChanged(app, event)
            value = app.Link5Slider_2.Value;
            app.panda_joint5 = deg2rad(value);
            
        end

        % Value changed function: Link4Slider_2
        function PandaLink4SliderValueChanged(app, event)
            value = app.Link4Slider_2.Value;
            app.panda_joint4 = deg2rad(value);
            
        end

        % Value changed function: Link7Slider
        function PandaLink7SliderValueChanged(app, event)
            value = app.Link7Slider.Value;
            app.panda_joint7 = deg2rad(value);
            
        end

        % Value changed function: Link2Slider_2
        function PandaLink2SliderValueChanged(app, event)
            value = app.Link2Slider_2.Value;
            app.panda_joint2 = deg2rad(value);
            
        end

        % Value changed function: Link1Slider_2
        function PandaLink1SliderValueChanged(app, event)
            value = app.Link1Slider_2.Value;
            app.panda_joint1 = deg2rad(value);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StateoutputEditFieldLabel = uilabel(app.UIFigure);
            app.StateoutputEditFieldLabel.Text = 'State output';
            app.StateoutputEditField = uieditfield(app.UIFigure, 'text');
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ResumeButton = uibutton(app.UIFigure, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.ResumeButton.Text = 'Resume';
            app.RestartButton = uibutton(app.UIFigure, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.RestartButton.Text = 'Restart';
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = 'Lamp';
            app.OnOfLamp = uilamp(app.UIFigure);
            app.LightCurtainsButton = uibutton(app.UIFigure, 'push');
            app.LightCurtainsButton.Text = 'Light Curtains';
            app.CollisionButton = uibutton(app.UIFigure, 'push');
            app.CollisionButton.Text = 'Collision ';
            app.EstopButton = uibutton(app.UIFigure, 'state');
            app.EstopButton.ValueChangedFcn = createCallbackFcn(app, @EstopButtonValueChanged, true);
            app.EstopButton.Text = 'Estop';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.UR3Tab = uitab(app.TabGroup);
            app.UR3Tab.Title = 'UR3';
            app.Link1Label = uilabel(app.UR3Tab);
            app.Link1Label.Text = 'Link 1';
            app.Link1Slider = uislider(app.UR3Tab);
            app.Link1Slider.Limits = [-180 180];
            app.Link1Slider.ValueChangedFcn = createCallbackFcn(app, @UR3Link1SliderValueChanged, true);
            app.Link2SliderLabel = uilabel(app.UR3Tab);
            app.Link2SliderLabel.Text = 'Link 2';
            app.Link2Slider = uislider(app.UR3Tab);
            app.Link2Slider.ValueChangedFcn = createCallbackFcn(app, @UR3Link2SliderValueChanged, true);
            app.Link3SliderLabel = uilabel(app.UR3Tab);
            app.Link3SliderLabel.Text = 'Link 3';
            app.Link3Slider = uislider(app.UR3Tab);
            app.Link3Slider.ValueChangedFcn = createCallbackFcn(app, @UR3Link3SliderValueChanged, true);
            app.Link4SliderLabel = uilabel(app.UR3Tab);
            app.Link4SliderLabel.Text = 'Link 4';
            app.Link4Slider = uislider(app.UR3Tab);
            app.Link4Slider.ValueChangedFcn = createCallbackFcn(app, @UR3Link4SliderValueChanged, true);
            app.Link5SliderLabel = uilabel(app.UR3Tab);
            app.Link5SliderLabel.Text = 'Link 5';
            app.Link5Slider = uislider(app.UR3Tab);
            app.Link5Slider.ValueChangedFcn = createCallbackFcn(app, @UR3Link5SliderValueChanged, true);
            app.Link6Slider_2Label = uilabel(app.UR3Tab);
            app.Link6Slider_2Label.Text = 'Link 6';
            app.Link6Slider_2 = uislider(app.UR3Tab);
            app.Link6Slider_2.ValueChangedFcn = createCallbackFcn(app, @UR3Link6SliderValueChanged, true);
            app.XEditFieldLabel = uilabel(app.UR3Tab);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UR3Tab, 'numeric');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @UR3XEditFieldValueChanged, true);
            app.ZEditFieldLabel = uilabel(app.UR3Tab);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.UR3Tab, 'numeric');
            app.ZEditField.ValueChangedFcn = createCallbackFcn(app, @UR3ZEditFieldValueChanged, true);
            app.YEditFieldLabel = uilabel(app.UR3Tab);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UR3Tab, 'numeric');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @UR3YEditFieldValueChanged, true);
            app.UR3CartesianControlLabel = uilabel(app.UR3Tab);
            app.UR3CartesianControlLabel.Text = 'UR3 Cartesian Control';
            app.PandaTab = uitab(app.TabGroup);
            app.PandaTab.Title = 'Panda';
            app.Link1Slider_2Label = uilabel(app.PandaTab);
            app.Link1Slider_2Label.Text = 'Link 1';
            app.Link1Slider_2 = uislider(app.PandaTab);
            app.Link1Slider_2.Limits = [0 180];
            app.Link1Slider_2.ValueChangedFcn = createCallbackFcn(app, @PandaLink1SliderValueChanged, true);
            app.Link2Slider_2Label = uilabel(app.PandaTab);
            app.Link2Slider_2Label.Text = 'Link 2';
            app.Link2Slider_2 = uislider(app.PandaTab);
            app.Link2Slider_2.Limits = [0 180];
            app.Link2Slider_2.ValueChangedFcn = createCallbackFcn(app, @PandaLink2SliderValueChanged, true);
            app.Link7SliderLabel = uilabel(app.PandaTab);
            app.Link7SliderLabel.Text = 'Link 7';
            app.Link7Slider = uislider(app.PandaTab);
            app.Link7Slider.Limits = [0 180];
            app.Link7Slider.ValueChangedFcn = createCallbackFcn(app, @PandaLink7SliderValueChanged, true);
            app.Link4Slider_2Label = uilabel(app.PandaTab);
            app.Link4Slider_2Label.Text = 'Link 4';
            app.Link4Slider_2 = uislider(app.PandaTab);
            app.Link4Slider_2.Limits = [0 180];
            app.Link4Slider_2.ValueChangedFcn = createCallbackFcn(app, @PandaLink4SliderValueChanged, true);
            app.Link5Slider_2Label = uilabel(app.PandaTab);
            app.Link5Slider_2Label.Text = 'Link 5';
            app.Link5Slider_2 = uislider(app.PandaTab);
            app.Link5Slider_2.Limits = [0 180];
            app.Link5Slider_2.ValueChangedFcn = createCallbackFcn(app, @PandaLink5SliderValueChanged, true);
            app.Link6SliderLabel = uilabel(app.PandaTab);
            app.Link6SliderLabel.Text = 'Link 6';
            app.Link6Slider = uislider(app.PandaTab);
            app.Link6Slider.Limits = [0 180];
            app.Link6Slider.ValueChangedFcn = createCallbackFcn(app, @PandaLink6SliderValueChanged, true);
            app.Link3Slider_2Label = uilabel(app.PandaTab);
            app.Link3Slider_2Label.Text = 'Link 3';
            app.Link3Slider_2 = uislider(app.PandaTab);
            app.Link3Slider_2.Limits = [0 180];
            app.Link3Slider_2.ValueChangedFcn = createCallbackFcn(app, @PandaLink3SliderValueChanged, true);
            app.XEditField_2Label = uilabel(app.PandaTab);
            app.XEditField_2Label.Text = 'X';
            app.XEditField_2 = uieditfield(app.PandaTab, 'numeric');
            app.XEditField_2.ValueChangedFcn = createCallbackFcn(app, @PandaXEditFieldValueChanged, true);
            app.YEditField_2Label = uilabel(app.PandaTab);
            app.YEditField_2Label.Text = 'Y';
            app.YEditField_2 = uieditfield(app.PandaTab, 'numeric');
            app.YEditField_2.ValueChangedFcn = createCallbackFcn(app, @PandaYEditFieldValueChanged, true);
            app.ZEditField_2Label = uilabel(app.PandaTab);
            app.ZEditField_2Label.Text = 'Z';
            app.ZEditField_2 = uieditfield(app.PandaTab, 'numeric');
            app.ZEditField_2.ValueChangedFcn = createCallbackFcn(app, @PandaZEditFieldValueChanged, true);
            app.PandaCartesianControlLabel = uilabel(app.PandaTab);
            app.PandaCartesianControlLabel.Text = 'Panda Cartesian Control';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end